classdef analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisConfiguratorUIFigure    matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        GenerateparametersfileButton    matlab.ui.control.Button
        OptionaldataPanel               matlab.ui.container.Panel
        MaxSignallengthhEditField       matlab.ui.control.NumericEditField
        MaxSignallengthhEditFieldLabel  matlab.ui.control.Label
        SamplingtimesecEditField        matlab.ui.control.NumericEditField
        SamplingtimesecLabel            matlab.ui.control.Label
        SnifferdataPanel                matlab.ui.container.Panel
        AMSdataPanel                    matlab.ui.container.Panel
        UtilityLauncherButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: UtilityLauncherButton
        function UtilityLauncherButtonPushed(app, event)
            app.CallingApp.GEDAUtilityLauncherUIFigure.Visible = 'on';
        end

        % Close request function: AnalysisConfiguratorUIFigure
        function AnalysisConfiguratorUIFigureCloseRequest(app, event)
            app.CallingApp.ConfigureAnalysisButton.Enable = 'on';
            app.CallingApp.GEDAUtilityLauncherUIFigure.Visible = 'on';
            delete(app)
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            app.CallingApp.GEDAUtilityLauncherUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisConfiguratorUIFigure = uifigure('Visible', 'off');
            app.AnalysisConfiguratorUIFigure.Name = 'Analysis Configurator';
            app.AnalysisConfiguratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnalysisConfiguratorUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.AnalysisConfiguratorUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.UtilityLauncherButton = uibutton(app.AnalysisConfiguratorUIFigure, 'push');
            app.UtilityLauncherButton.ButtonPushedFcn = createCallbackFcn(app, @UtilityLauncherButtonPushed, true);
            app.UtilityLauncherButton.Text = 'Utility Launcher';
            app.AMSdataPanel = uipanel(app.AnalysisConfiguratorUIFigure);
            app.AMSdataPanel.Title = 'AMS data';
            app.SnifferdataPanel = uipanel(app.AnalysisConfiguratorUIFigure);
            app.SnifferdataPanel.Title = 'Sniffer data';
            app.OptionaldataPanel = uipanel(app.AnalysisConfiguratorUIFigure);
            app.OptionaldataPanel.Title = 'Optional data';
            app.SamplingtimesecLabel = uilabel(app.OptionaldataPanel);
            app.SamplingtimesecLabel.Text = 'Sampling time (sec)   ';
            app.SamplingtimesecEditField = uieditfield(app.OptionaldataPanel, 'numeric');
            app.MaxSignallengthhEditFieldLabel = uilabel(app.OptionaldataPanel);
            app.MaxSignallengthhEditFieldLabel.Text = 'Max. Signal length (h)';
            app.MaxSignallengthhEditField = uieditfield(app.OptionaldataPanel, 'numeric');
            app.GenerateparametersfileButton = uibutton(app.AnalysisConfiguratorUIFigure, 'push');
            app.GenerateparametersfileButton.Text = 'Generate parameters file';
            app.FileNameEditFieldLabel = uilabel(app.AnalysisConfiguratorUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.AnalysisConfiguratorUIFigure, 'text');
            % Show the figure after all components are created
            app.AnalysisConfiguratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisConfiguratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisConfiguratorUIFigure)
        end
    end
end