classdef job < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ViewUtilityLauncherButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ConfigureJobButton.Enable = 'on';
            app.CallingApp.GEDAUtilityLauncherUIFigure.Visible = 'on';
            delete(app)
        end

        % Button pushed function: ViewUtilityLauncherButton
        function ViewUtilityLauncherButtonPushed(app, event)
            app.CallingApp.GEDAUtilityLauncherUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ViewUtilityLauncherButton = uibutton(app.UIFigure, 'push');
            app.ViewUtilityLauncherButton.ButtonPushedFcn = createCallbackFcn(app, @ViewUtilityLauncherButtonPushed, true);
            app.ViewUtilityLauncherButton.Text = 'View Utility Launcher ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = job(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end