classdef launch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GEDAUtilityLauncherUIFigure  matlab.ui.Figure
        GEDAvisualutilitytoolLabel   matlab.ui.control.Label
        CheckResultsButton           matlab.ui.control.Button
        ConfigureJobButton           matlab.ui.control.Button
        ConfigureAnalysisButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        launchapparg1; % Description
    end
    
    properties (Access = private)
        AnalysisApp; % Description
        JobApp;
        ResultsApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfigureAnalysisButton
        function ConfigureAnalysisButtonPushed(app, event)
            app.AnalysisApp = analysis(app);
            app.ConfigureAnalysisButton.Enable = 'off';
            app.GEDAUtilityLauncherUIFigure.Visible = 'off';
        end

        % Button pushed function: ConfigureJobButton
        function ConfigureJobButtonPushed(app, event)
            app.JobApp = job(app);
            %app.AnalysisApp = analysis(app);
            app.ConfigureJobButton.Enable = 'off';
            app.GEDAUtilityLauncherUIFigure.Visible = 'off';
        end

        % Button pushed function: CheckResultsButton
        function CheckResultsButtonPushed(app, event)
            app.ResultsApp = results(app);
            app.ConfigureResultsButton.Enable = 'off';
            app.GEDAUtilityLauncherUIFigure.Visible = 'off';
        end

        % Close request function: GEDAUtilityLauncherUIFigure
        function GEDAUtilityLauncherUIFigureCloseRequest(app, event)
            delete(app.AnalysisApp)
            delete(app.JobApp)
            delete(app.ResultsApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GEDAUtilityLauncherUIFigure = uifigure('Visible', 'off');
            app.GEDAUtilityLauncherUIFigure.Name = 'GEDA Utility Launcher';
            app.GEDAUtilityLauncherUIFigure.CloseRequestFcn = createCallbackFcn(app, @GEDAUtilityLauncherUIFigureCloseRequest, true);
            app.ConfigureAnalysisButton = uibutton(app.GEDAUtilityLauncherUIFigure, 'push');
            app.ConfigureAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureAnalysisButtonPushed, true);
            app.ConfigureAnalysisButton.Text = 'Configure Analysis';
            app.ConfigureJobButton = uibutton(app.GEDAUtilityLauncherUIFigure, 'push');
            app.ConfigureJobButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureJobButtonPushed, true);
            app.ConfigureJobButton.Text = 'Configure Job';
            app.CheckResultsButton = uibutton(app.GEDAUtilityLauncherUIFigure, 'push');
            app.CheckResultsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckResultsButtonPushed, true);
            app.CheckResultsButton.Text = 'Check Results';
            app.GEDAvisualutilitytoolLabel = uilabel(app.GEDAUtilityLauncherUIFigure);
            app.GEDAvisualutilitytoolLabel.Text = 'GEDA visual utility tool ';
            % Show the figure after all components are created
            app.GEDAUtilityLauncherUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = launch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GEDAUtilityLauncherUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GEDAUtilityLauncherUIFigure)
        end
    end
end